classdef tripleangle_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        GimbalsCheckBox  matlab.ui.control.CheckBox
        pb_reset         matlab.ui.control.Button
        pb_front         matlab.ui.control.Button
        pb_side          matlab.ui.control.Button
        pb_top           matlab.ui.control.Button
        pb_euler         matlab.ui.control.Button
        pb_RPY           matlab.ui.control.Button
        text3            matlab.ui.control.Label
        text2            matlab.ui.control.Label
        text1            matlab.ui.control.Label
        popupmenu3       matlab.ui.control.DropDown
        popupmenu2       matlab.ui.control.DropDown
        slider3          matlab.ui.control.Slider
        slider2          matlab.ui.control.Slider
        slider1          matlab.ui.control.Slider
        popupmenu1       matlab.ui.control.DropDown
        axes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        plane % Description
        ring1 % Description
        ring2 % Description
        ring3 % Description
        h1 % Description
    end
    
    methods (Access = private)

        
        function update(app)
            
            function R = RM(theta, which)
                theta = theta * pi/180;
                
                switch which
                    case 'Rx'
                        R = rotmx(theta);
                    case 'Ry'
                        R = rotmy(theta);
                    case 'Rz'
                        R = rotmz(theta);
                end
            end
            
            % compute the three rotation matrices
            
            R1 = RM( app.slider1.Value, app.popupmenu1.Value);
            R2 = RM( app.slider2.Value, app.popupmenu2.Value);
            R3 = RM( app.slider3.Value, app.popupmenu3.Value);
            
            
            % display the three frames
            %trplot(R1*R2*R3, 'handle', app.h1);
            
            app.ring3.Matrix = r2t( R1 * rotmy(pi/2) );
            % Ry
            app.ring2.Matrix = r2t( R1*R2 * rotmz(pi/2) );
            % Rx
            app.ring1.Matrix = r2t( R1*R2*R3 * rotmx(pi/2) );
            
            app.plane.Matrix = r2t( R1*R2*R3 * rotmy(pi/2)*rotmz(pi/2) );
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function tripleangle_OpeningFcn(app, varargin)

       
            % process options
            opt.wait = false;
            opt.which = {'rpy', 'euler'};
            
            [opt,args] = tb_optparse(opt, varargin);
            
%             if length(args) > 0 && ischar(args{1})
%                 s = args{1};
%                 try
%                     set(app.popupmenu1, 'Value', strfind('xyz', s(1)));
%                     set(app.popupmenu2, 'Value', strfind('xyz', s(2)));
%                     set(app.popupmenu3, 'Value', strfind('xyz', s(3)));
%                     if length(args) > 1
%                         args = args{2:end};
%                     else
%                         args = {};
%                     end
%                     opt.which = 'none';
%                 catch me
%                     % doesnt match a rotation string, go with 'rpy'
%                     opt.which = 'rpy';
%             
%                 end
%             end
            
            % set initial slider to axis mapping
            switch opt.which
                case 'rpy'
                    app.popupmenu1.Value = 'Rz';
                    app.popupmenu2.Value = 'Ry';
                    app.popupmenu3.Value = 'Rx';
                case 'euler'
                    app.popupmenu1.Value = 'Rz';
                    app.popupmenu2.Value = 'Ry';
                    app.popupmenu3.Value = 'Rz';
            end
            
            % draw the axes at null rotation
            %app.h1 = trplot(eye(3,3), 'color', 'r', args{:});
            
            
            % set the initial value of text fields
            app.text1.Text = sprintf('%.1f', app.slider1.Value);
            app.text2.Text =  sprintf('%.1f', app.slider2.Value);
            app.text3.Text =  sprintf('%.1f', app.slider3.Value);
             
            % configure the graphics
            ax = app.axes;
            axis(ax, 1.5*[-1 1 -1 1 -1 1]);
            daspect(ax, [1 1 1]);
            light(ax, 'Position', [0 0 5]);
            light(ax, 'Position', [2 2 1]);
            grid(ax, 'on')
            xlabel(ax, 'X')
            ylabel(ax, 'Y');
            zlabel(ax, 'Z');
            
            % establish transforms
            app.plane = hgtransform(ax, 'Tag', 'plane');
            app.ring1 = hgtransform(ax, 'Tag', 'ring1');
            app.ring2 = hgtransform(ax, 'Tag', 'ring2');
            app.ring3 = hgtransform(ax, 'Tag', 'ring3');
            
            %% load the models
            
            % read the plane model
            fprintf('reading STL models...');
            tr = stlread( 'spitfire_assy-gear_up.stl');
            V = tr.Points;
            F = tr.ConnectivityList;
            
            % shift origin to wing centre line
            V = bsxfun(@plus, V, [0 0 75]);
            patch(ax, 'Faces', F, 'Vertices', V/180, ...
                'FaceColor', 0.8*[1.0000    0.7812    0.4975], 'EdgeAlpha', 0, ...
                'Parent', app.plane);
            
            %% read the gimbal rings
            
            % inner
            fprintf('.');
            tr = stlread('gimbal-ring1.stl');
            V = tr.Points;
            F = tr.ConnectivityList;
            patch(ax, 'Faces', F, 'Vertices', V*1, ...
                'FaceColor', 'b', 'EdgeAlpha', 0, ...
                'Parent', app.ring1);
            
            % middle
            fprintf('.');
            
            tr = stlread('gimbal-ring2.stl');
            V = tr.Points;
            F = tr.ConnectivityList;
            patch(ax, 'Faces', F, 'Vertices', V*1.1, ...
                'FaceColor', 'g',  'EdgeAlpha', 0, ...
                'Parent', app.ring2);
            
            % outer
            fprintf('.');
            
            tr = stlread('gimbal-ring3.stl');
            V = tr.Points;
            F = tr.ConnectivityList;
            patch(ax, 'Faces', F, 'Vertices', V*1.1^2, ...
                'FaceColor', 'r', 'EdgeAlpha', 0, ...
                'Parent', app.ring3);
            
            fprintf('\rSupermarine Spitfire Mk VIII by Ed Morley @GRABCAD\n');
            fprintf('Gimbal models by Peter Corke using OpenSCAD\n');             
            % enable mouse-based 3D rotation
            rotate3d(ax, 'on')
            view(ax, 50, 22);
            
            app.update();
                       
            % UIWAIT makes tripleang wait for user response (see UIRESUME)
            if opt.wait
                uiwait(app.figure1);
            end
        end

        % Button pushed function: pb_RPY
        function pb_RPY_Callback(app, event)
                app.popupmenu1.Value = 'Rz';
                app.popupmenu2.Value = 'Ry';
                app.popupmenu3.Value = 'Rx';
                app.update();
        end

        % Button pushed function: pb_euler
        function pb_euler_Callback(app, event)
                app.popupmenu1.Value = 'Ry';
                app.popupmenu2.Value = 'Rz';
                app.popupmenu3.Value = 'Ry';
                app.update();
        end

        % Button pushed function: pb_front
        function pb_front_Callback(app, event)

                view(app.axes, 90, 0);
                app.update();
        end

        % Button pushed function: pb_reset
        function pb_reset_Callback(app, event)
            app.popupmenu1.Value = 'Rx';
            app.popupmenu2.Value = 'Ry';
            app.popupmenu3.Value = 'Rz';
            
            app.slider1.Value = 0;
            app.slider2.Value = 0;
            app.slider3.Value = 0;
            app.text1.Text = sprintf('%.1f', 0);
            app.text2.Text = sprintf('%.1f', 0);
            app.text3.Text = sprintf('%.1f', 0);
            
            app.update();
            view(app.axes, 50, 22);
        end

        % Button pushed function: pb_side
        function pb_side_Callback(app, event)
                view(app.axes, 0, 0);
        end

        % Button pushed function: pb_top
        function pb_top_Callback(app, event)
                view(app.axes, 90, 90)
        end

        % Value changing function: slider1
        function slider1_Callback(app, event)
            app.slider1.Value = event.Value;
            app.text1.Text = sprintf('%.1f', app.slider1.Value);
            app.update();
        end

        % Value changing function: slider2
        function slider2_Callback(app, event)
            app.slider2.Value = event.Value;
            app.text2.Text = sprintf('%.1f', app.slider2.Value);
            app.update();
        end

        % Value changing function: slider3
        function slider3_Callback(app, event)
            app.slider3.Value = event.Value;
            app.text3.Text = sprintf('%.1f', app.slider3.Value);
            app.update();
        end

        % Value changed function: GimbalsCheckBox
        function cb_gimbals(app, event)
            value = app.GimbalsCheckBox.Value;
            switch value
                case 0
                    app.ring1.Visible = 'off';
                    app.ring2.Visible = 'off';
                    app.ring3.Visible = 'off';
                case 1
                    app.ring1.Visible = 'on';
                    app.ring2.Visible = 'on';
                    app.ring3.Visible = 'on';
            end
            app.update();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Triple angle visualizer';
            app.axes = uiaxes(app.figure1);
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'Rx', 'Ry', 'Rz'};
            app.popupmenu1.Value = 'Rx';
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [-180 180];
            app.slider1.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.slider1.ValueChangingFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider2 = uislider(app.figure1);
            app.slider2.Limits = [-180 180];
            app.slider2.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.slider2.ValueChangingFcn = createCallbackFcn(app, @slider2_Callback, true);
            app.slider2.MinorTicks = [];
            app.slider3 = uislider(app.figure1);
            app.slider3.Limits = [-180 180];
            app.slider3.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.slider3.ValueChangingFcn = createCallbackFcn(app, @slider3_Callback, true);
            app.slider3.MinorTicks = [];
            app.popupmenu2 = uidropdown(app.figure1);
            app.popupmenu2.Items = {'Rx', 'Ry', 'Rz'};
            app.popupmenu2.Value = 'Rx';
            app.popupmenu3 = uidropdown(app.figure1);
            app.popupmenu3.Items = {'Rx', 'Ry', 'Rz'};
            app.popupmenu3.Value = 'Rx';
            app.text1 = uilabel(app.figure1);
            app.text1.Text = 'Static Text';
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'Static Text';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = 'Static Text';
            app.pb_RPY = uibutton(app.figure1, 'push');
            app.pb_RPY.ButtonPushedFcn = createCallbackFcn(app, @pb_RPY_Callback, true);
            app.pb_RPY.Text = 'ZYX RPY';
            app.pb_euler = uibutton(app.figure1, 'push');
            app.pb_euler.ButtonPushedFcn = createCallbackFcn(app, @pb_euler_Callback, true);
            app.pb_euler.Text = 'YZY Euler';
            app.pb_top = uibutton(app.figure1, 'push');
            app.pb_top.ButtonPushedFcn = createCallbackFcn(app, @pb_top_Callback, true);
            app.pb_top.Text = 'Top (-z)';
            app.pb_side = uibutton(app.figure1, 'push');
            app.pb_side.ButtonPushedFcn = createCallbackFcn(app, @pb_side_Callback, true);
            app.pb_side.Text = 'Side (-y)';
            app.pb_front = uibutton(app.figure1, 'push');
            app.pb_front.ButtonPushedFcn = createCallbackFcn(app, @pb_front_Callback, true);
            app.pb_front.Text = 'Front (-x)';
            app.pb_reset = uibutton(app.figure1, 'push');
            app.pb_reset.ButtonPushedFcn = createCallbackFcn(app, @pb_reset_Callback, true);
            app.pb_reset.Text = 'Reset';
            app.GimbalsCheckBox = uicheckbox(app.figure1);
            app.GimbalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @cb_gimbals, true);
            app.GimbalsCheckBox.Text = 'Gimbals';
            app.GimbalsCheckBox.Value = true;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tripleangle_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)tripleangle_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end